classdef CRN_SIMULATOR_GUI_V0_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChooseTheParameterofCalculationsDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UitlizationButton               matlab.ui.control.Button
        PUInterferenaceRatioButton      matlab.ui.control.Button
        ThroughputButton_10             matlab.ui.control.Button
        SUCollisionButton               matlab.ui.control.Button
        SUSuccesfulTXButton             matlab.ui.control.Button
        CooperativeFalseAlarmButton     matlab.ui.control.Button
        CooperativeMissDetectionButton  matlab.ui.control.Button
        S4SuccessfulTXButton            matlab.ui.control.Button
        ChangingLoadsButton_2           matlab.ui.control.Button
        clearButton                     matlab.ui.control.Button
    end


    methods (Access = private)

        % Callback function
        function ChooseTheParameterofCalculationsDropDownValueChanged(app, event)
             
        end

        % Button pushed function: ThroughputButton_10
        function ThroughputButton_10Pushed(app, event)
            % Ploting Throughput Results 
            %%%%%%%% names of the files that will be imported 
            Throughput25 = csvread ('THROUGHPUT_VS_BAND_FOR_ALLSUs_PPU(.25).csv'); 
            Throughput15 = csvread ('THROUGHPUT_VS_BAND_FOR_ALLSUs_PPU(.15).csv'); 
            Throughput00 = csvread ('THROUGHPUT_VS_BAND_FOR_ALLSUs_PPU(.0).csv'); 
            %%%%%%% importing the first colume of each trail to get the graph going on 
            
            x1= Throughput00 (:,1); % this is the results from probabilty of 0.0 
            y1= Throughput15 (:,1) ;% this is the results from probabilty of 0.15
            z1= Throughput25 (:,1); % this is the results from probabilty of 0.25
            
            %%%%%%%%%% the Drawing process 
            hold (app.UIAxes) ;
            plot(app.UIAxes,x1,'Color','blue','LineWidth',2.0); hold  (app.UIAxes); 
            plot(app.UIAxes,y1,'Color','red','linewidth',2.0,'LineStyle','-.'); hold  (app.UIAxes);
            plot(app.UIAxes,z1,'Color','green','LineWidth',2.0,'LineStyle','-.'); hold  (app.UIAxes);
            xlabel(app.UIAxes,'Bands','Fontweight','Bold');
            ylabel(app.UIAxes,'Throughput','Fontweight','Bold');
            title (app.UIAxes,'Throughput VS BAND FOR ALL SUs','Fontweight','Bold');
            legend (app.UIAxes,'Probabilty Of ACtivation 0.0 No Cooperation ','Probabilty Of ACtivation 0.15 No Cooperation','Probabilty Of ACtivation 0.25 No Cooperation');
            
            ylim (app.UIAxes ,[0 1]);
            
            grid (app.UIAxes); 
            
            
        end

        % Button pushed function: UitlizationButton
        function UitlizationButtonPushed(app, event)
            % Ploting Utilization Results 
            %%%%%%%% names of the files that will be imported 
            Utilization25 = csvread ('Utilization_VS_BAND_FOR_ALLSUs_PPU(.25).csv'); 
            Utilization15 = csvread ('Utilization_VS_BAND_FOR_ALLSUs_PPU(.15).csv'); 
            Utilization00 = csvread ('Utilization_VS_BAND_FOR_ALLSUs_PPU(.0).csv'); 
            
            
            %%%%%%% importing the first colume of each trail to get the graph going on 
            
            x2= Utilization00 (:,1); % this is the results from probabilty of 0.0 
            y2= Utilization15 (:,1) ;% this is the results from probabilty of 0.15
            z2= Utilization25 (:,1) ;% this is the results from probabilty of 0.25
            
            %%%%%%%%%% the Drawing process
          
           plot(app.UIAxes,x2,'Color','blue','LineWidth',2.0);
            hold off (app.UIAxes);
            plot(app.UIAxes,y2,'Color','red','linewidth',2.0,'LineStyle','-.'); hold  (app.UIAxes);
            plot(app.UIAxes,z2,'Color','green','LineWidth',2.0,'LineStyle','-.');hold  (app.UIAxes);
            xlabel(app.UIAxes,'Bands','Fontweight','Bold');
            ylabel(app.UIAxes,'Utilization','Fontweight','Bold');
            title (app.UIAxes,'Utilization VS BAND FOR ALL SUs','Fontweight','Bold');
            legend (app.UIAxes,'Probabilty Of ACtivation 0.0 No Cooperation','Probabilty Of ACtivation 0.15 No Cooperation','Probabilty Of ACtivation 0.25 No Cooperation ');
  grid (app.UIAxes);             
        end

        % Button pushed function: SUSuccesfulTXButton
        function SUSuccesfulTXButtonPushed(app, event)
            % Ploting SU sucessful transmission  Results 
            %%%%%%%% names of the files that will be imported 
            SucessfulTransmission25 = csvread ('Successful_VS_Time_FOR_SU4_PPU(.25).csv'); 
            SucessfulTransmission15 = csvread ('Successful_VS_Time_FOR_SU4_PPU(.15).csv'); 
            SucessfulTransmission00 = csvread ('Successful_VS_Time_FOR_SU4_PPU(.0).csv'); 
            
            
            %%%%%%% importing the first colume of each trail to get the graph going on 
            
            x3= SucessfulTransmission00 (:,1); % this is the results from probabilty of 0.0 
            y3=  SucessfulTransmission15 (:,1); % this is the results from probabilty of 0.15
            z3= SucessfulTransmission25 (:,1); % this is the results from probabilty of 0.25
            
            %%%%%%%%%% the Drawing process 
            hold (app.UIAxes)
            plot(app.UIAxes,x3,'Color','blue','LineWidth',2.0);
            plot(app.UIAxes,y3,'Color','red','linewidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,z3,'Color','green','LineWidth',2.0,'LineStyle','-.');
            xlabel(app.UIAxes,'Bands','Fontweight','Bold');
            ylabel(app.UIAxes,'SucessfulTransmission','Fontweight','Bold');
            title (app.UIAxes,'SucessfulTransmission VS BAND FOR ALL SUs','Fontweight','Bold');
            legend (app.UIAxes,'Probabilty Of ACtivation 0.0 No Cooperation ','Probabilty Of ACtivation 0.15  No Cooperation','Probabilty Of ACtivation 0.25 No Cooperation ');
            grid (app.UIAxes);
            
        end

        % Button pushed function: SUCollisionButton
        function SUCollisionButtonPushed(app, event)
            % Ploting SU Collisions Results 
            %%%%%%%% names of the files that will be imported 
            Collision25 = csvread ('Collision_VS_SUID_FOR_ALLBANDs_PPU(.25).csv'); 
            Collision15 = csvread ('Collision_VS_SUID_FOR_ALLBANDs_PPU(.15).csv'); 
            Collision00 = csvread ('Collision_VS_SUID_FOR_ALLBANDs_PPU(.0).csv'); 
            
            
            %%%%%%% importing the first colume of each trail to get the graph going on 
            
            x4= Collision00 (:,1); % this is the results from probabilty of 0.0 
            y4= Collision15 (:,1) ;% this is the results from probabilty of 0.15
            z4= Collision25 (:,1) ;% this is the results from probabilty of 0.25
            
            %%%%%%%%%% the Drawing process 
            hold (app.UIAxes)
            plot(app.UIAxes,x4,'Color','blue','LineWidth',2.0);
            plot(app.UIAxes,y4,'Color','red','linewidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,z4,'Color','green','LineWidth',2.0,'LineStyle','-.');
            xlabel(app.UIAxes,'Bands','Fontweight','Bold');
            ylabel(app.UIAxes,'Collision','Fontweight','Bold');
            title (app.UIAxes,'Collision VS Time Slot FOR ALL SUs','Fontweight','Bold');
            legend (app.UIAxes,'Probabilty Of ACtivation 0.0  No Cooperation ','Probabilty Of ACtivation 0.15 No Cooperation','Probabilty Of ACtivation 0.25 No Cooperation ');
           ylim (app.UIAxes ,[0 10]);
            grid (app.UIAxes);
            
        end

        % Button pushed function: PUInterferenaceRatioButton
        function PUInterferenaceRatioButtonPushed(app, event)
            % Ploting PU Interferance Ratio Results 
            %%%%%%%% names of the files that will be imported 
            PUinterferance25 = csvread ('PU_Interfrance_ratio(.25).csv'); 
            PUinterferance15 = csvread ('PU_Interfrance_ratio(.15).csv'); 
            PUinterferance00 = csvread ('PU_Interfrance_ratio(.0).csv'); 
            
            
            %%%%%%% importing the first colume of each trail to get the graph going on 
            
            x5= PUinterferance00 (:,1); % this is the results from probabilty of 0.0 
            y5= PUinterferance15 (:,1) ;% this is the results from probabilty of 0.15
            z5= PUinterferancet25 (:,1); % this is the results from probabilty of 0.25
            
            %%%%%%%%%% the Drawing process 
            hold (app.UIAxes)
            plot(app.UIAxes,x5,'Color','blue','LineWidth',2.0);
            plot(app.UIAxes,y5,'Color','red','linewidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,z5,'Color','green','LineWidth',2.0,'LineStyle','-.');
            xlabel(app.UIAxes,'Bands','Fontweight','Bold');
            ylabel(app.UIAxes,'PUinterferance','Fontweight','Bold');
            title (app.UIAxes,'PUinterferance VS BAND FOR ALL PUs','Fontweight','Bold');
            legend (app.UIAxes,'Probabilty Of ACtivation 0.0 No Cooperation','Probabilty Of ACtivation 0.15 No Cooperation','Probabilty Of ACtivation 0.25 No Cooperation ');
            grid (app.UIAxes);


        end

        % Button pushed function: S4SuccessfulTXButton
        function S4SuccessfulTXButtonPushed(app, event)
            % Ploting Sucessful SU4  VS Time  Results 
            %%%%%%%% names of the files that will be imported 
            SU4BANDS25 = csvread ('Successful_VS_Time_FOR_SU4_PPU(.25).csv'); 
            SU4BANDS15 = csvread ('Successful_VS_Time_FOR_SU4_PPU(.15).csv'); 
            SU4BANDS00 = csvread ('Successful_VS_Time_FOR_SU4_PPU(.0).csv'); 
            
            
            %%%%%%% importing the first colume of each trail to get the graph going on 
            
            x6= SU4BANDS00 (:,1); % this is the results from probabilty of 0.0 
            y6= SU4BANDSt15 (:,1); % this is the results from probabilty of 0.15
            z6= SU4BANDS25 (:,1) ;% this is the results from probabilty of 0.25
            
            %%%%%%%%%% the Drawing process 
            hold (app.UIAxes)
            plot(app.UIAxes,x6,'Color','blue','LineWidth',2.0);
            plot(app.UIAxes,y6,'Color','red','linewidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,z6,'Color','green','LineWidth',2.0,'LineStyle','-.');
            xlabel(app.UIAxes,'Time SLOTS','Fontweight','Bold');
            ylabel(app.UIAxes,'SU4BANDS','Fontweight','Bold');
            title (app.UIAxes,'SU4BANDS VS Time slots for SU4','Fontweight','Bold');
            legend (app.UIAxes,'Probabilty Of ACtivation 0.0 No Cooperation','Probabilty Of ACtivation 0.15 No Cooperation','Probabilty Of ACtivation 0.25 No Cooperation');
            grid (app.UIAxes);
        end

        % Button pushed function: CooperativeFalseAlarmButton
        function CooperativeFalseAlarmButtonPushed(app, event)
            % Ploting False alaram for all SUs  VS Time  Results 
            %%%%%%%% names of the files that will be imported 
            SUFA25 = csvread ('PFA_VS_SUID_FOR_ALLBANDs_PPU(.25).csv'); 
            SUFA15 = csvread ('PFA_VS_SUID_FOR_ALLBANDs_PPU(.15).csv'); 
            SUFA00 = csvread ('PFA_VS_SUID_FOR_ALLBANDs_PPU(.0).csv'); 
            SUFAcop00 = csvread ('PFA_VS_SUID_FOR_ALLBANDs_Cooperative_PPU(.0).csv');
            SUFAcop15 = csvread ('PFA_VS_SUID_FOR_ALLBANDs_Cooperative_PPU(.15).csv');
            SUFAcop25 = csvread ('PFA_VS_SUID_FOR_ALLBANDs_Cooperative_PPU(.25).csv');

            
            %%%%%%% importing the first colume of each trail to get the graph going on 
            
            x7= SUFA00 (:,1); % this is the results from probabilty of 0.0 
            y7= SUFA15 (:,1) ;% this is the results from probabilty of 0.15
            z7= SUFA25 (:,1) ;% this is the results from probabilty of 0.25
            x7cop= SUFAcop00 (:,1); % this is the results from probabilty of 0.0 
            y7cop= SUFAcop15(:,1) ;% this is the results from probabilty of 0.15
            z7cop= SUFAcop25(:,1) ;% this is the results from probabilty of 0.25
            
            
            %%%%%%%%%% the Drawing process 
            hold (app.UIAxes)
            plot(app.UIAxes,x7,'Color','blue','LineWidth',2.0);
            plot(app.UIAxes,y7,'Color','red','linewidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,z7,'Color','green','LineWidth',2.0,'LineStyle','-.');
            xlabel(app.UIAxes,'Time SLOT','Fontweight','Bold');
            ylabel(app.UIAxes,'SU False Alarms Avereged Over Time Slots','Fontweight','Bold');
            title (app.UIAxes,'SU False Alarms Avereged Over Time Slots For Each SU','Fontweight','Bold');
            legend (app.UIAxes,'Probabilty Of ACtivation 0.0 No Cooperation ','Probabilty Of ACtivation 0.15 No Cooperation','Probabilty Of ACtivation 0.25 No Cooperation ','Probabilty Of ACtivation 0.0 With Cooperation ','Probabilty Of ACtivation 0.15 With Cooperation','Probabilty Of ACtivation 0.25 With Cooperation ');
            grid (app.UIAxes);
        end

        % Button pushed function: CooperativeMissDetectionButton
        function CooperativeMissDetectionButtonPushed(app, event)
            % Ploting Miss-Detecction for all SUs  VS Time  Results 
            %%%%%%%% names of the files that will be imported 
            SUMD25 = csvread ('PMD_VS_SUID_FOR_ALLBANDs_PPU(.25).csv'); 
            SUMD15 = csvread ('PMD_VS_SUID_FOR_ALLBANDs_PPU(.15).csv'); 
            SUMD00 = csvread ('PMD_VS_SUID_FOR_ALLBANDs_PPU(.0).csv'); 
            SUMDCop25 = csvread ('PMD_VS_SUID_FOR_ALLBANDs_Cooperative_PPU(.25).csv'); 
            SUMDCop15 = csvread ('PMD_VS_SUID_FOR_ALLBANDs_Cooperative_PPU(.15).csv'); 
           SUMDCop00 = csvread ('PMD_VS_SUID_FOR_ALLBANDs_Cooperative_PPU(.00).csv'); 


            %%%%%%% importing the first colume of each trail to get the graph going on 
            
            x8= SUMD00 (:,1); % this is the results from probabilty of 0.0 
            y8= SUMD15 (:,1) ;% this is the results from probabilty of 0.15
            z8= SUMD25 (:,1) ;% this is the results from probabilty of 0.25
           
            X8cop= SUMDCop00 (:,1); % this is the results from probabilty of 0.0 With cooperation 
            y8cop= SUMDCop15 (:,1); % this is the results from probabilty of 0.15 With cooperation 
            z8cop= SUMDCop25 (:,1);% this is the results from probabilty of 0.25 With cooperation 
            
            %%%%%%%%%% the Drawing process 
            hold (app.UIAxes)
            plot(app.UIAxes,x8,'Color','blue','LineWidth',2.0);
            plot(app.UIAxes,y8,'Color','red','linewidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,z8,'Color','green','LineWidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,X8cop,'Color','green','LineWidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,y8cop,'Color','green','LineWidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,z8cop,'Color','green','LineWidth',2.0,'LineStyle','-.');
            xlabel(app.UIAxes,'Time slot','Fontweight','Bold');
            ylabel(app.UIAxes,'SU Miss detection Avereged Over Time Slots','Fontweight','Bold');
            title (app.UIAxes,'SU Miss Detection Avereged Over Time Slots For Each SU','Fontweight','Bold');
            legend (app.UIAxes,'Probabilty Of ACtivation 0.0 No Cooperation ','Probabilty Of ACtivation 0.15 No Cooperation','Probabilty Of ACtivation 0.25 No Cooperation ','Probabilty Of ACtivation 0.0 With Cooperation ','Probabilty Of ACtivation 0.15 With Cooperation','Probabilty Of ACtivation 0.25 With Cooperation ');
            grid (app.UIAxes);
            
        end

        % Button pushed function: ChangingLoadsButton_2
        function ChangingLoadsButton_2Pushed(app, event)
            % Ploting Changing The Load VS Bands Taken For SUs for all SUs  VS Time  Results 
            %%%%%%%% names of the files that will be imported 
            
            NOOFSU5 = csvread ('NUMBER_0F_SU5.csv'); 
            NOOFSU8 = csvread ('NUMBER_0F_SU8.csv'); 
            NOOFSU10 = csvread ('NUMBER_0F_SU10.csv'); 
            NOOFSU12 = csvread ('NUMBER_0F_SU12.csv'); 
            NOOFSU15 = csvread ('NUMBER_0F_SU15.csv'); 
            
            
            %%%%%%% importing the first colume of each trail to get the graph going on 
            
            x9= NOOFSU5 (:,1) ;% this is the results from having 5 SU 
            y9= NOOFSU8 (:,1) ;% this is the results from having 8 SU 
            z9= NOOFSU10 (:,1) ;% this is the results from having 10 SU
            H9= NOOFSU12 (:,1) ;% this is the results from having 12 SU 
            G9= NOOFSU15 (:,1) ;% this is the results from having 15 SU 
            
            %%%%%%%%%% the Drawing process 
            hold (app.UIAxes)
            plot(app.UIAxes,x9,'Color','blue','LineWidth',2.0);
            plot(app.UIAxes,y9,'Color','red','linewidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,z9,'Color','green','LineWidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,H9,'Color','black','LineWidth',2.0,'LineStyle','-.');
            plot(app.UIAxes,G9,'Color','yellow','LineWidth',2.0,'LineStyle','-.');
            
            xlabel(app.UIAxes,'SU ID','Fontweight','Bold');
            ylabel(app.UIAxes,'Average Value of Used Bands for each SU  ','Fontweight','Bold');
            title (app.UIAxes,'Changing The Load VS Bands Taken For SUs for all SUs','Fontweight','Bold');
            legend (app.UIAxes,'Probabilty Of ACtivation 0.0 ','Probabilty Of ACtivation 0.15','Probabilty Of ACtivation 0.25 ');
            grid (app.UIAxes);


        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            hold off ; 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ChooseTheParameterofCalculationsDropDownLabel = uilabel(app.UIFigure);
            app.ChooseTheParameterofCalculationsDropDownLabel.Text = 'Choose The Parameter of Calculations';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UitlizationButton = uibutton(app.UIFigure, 'push');
            app.UitlizationButton.ButtonPushedFcn = createCallbackFcn(app, @UitlizationButtonPushed, true);
            app.UitlizationButton.Text = 'Uitlization';
            app.PUInterferenaceRatioButton = uibutton(app.UIFigure, 'push');
            app.PUInterferenaceRatioButton.ButtonPushedFcn = createCallbackFcn(app, @PUInterferenaceRatioButtonPushed, true);
            app.PUInterferenaceRatioButton.Text = 'PU Interferenace Ratio';
            app.ThroughputButton_10 = uibutton(app.UIFigure, 'push');
            app.ThroughputButton_10.ButtonPushedFcn = createCallbackFcn(app, @ThroughputButton_10Pushed, true);
            app.ThroughputButton_10.Text = 'Throughput';
            app.SUCollisionButton = uibutton(app.UIFigure, 'push');
            app.SUCollisionButton.ButtonPushedFcn = createCallbackFcn(app, @SUCollisionButtonPushed, true);
            app.SUCollisionButton.Text = 'SU Collision';
            app.SUSuccesfulTXButton = uibutton(app.UIFigure, 'push');
            app.SUSuccesfulTXButton.ButtonPushedFcn = createCallbackFcn(app, @SUSuccesfulTXButtonPushed, true);
            app.SUSuccesfulTXButton.Text = 'SU Succesful TX ';
            app.CooperativeFalseAlarmButton = uibutton(app.UIFigure, 'push');
            app.CooperativeFalseAlarmButton.ButtonPushedFcn = createCallbackFcn(app, @CooperativeFalseAlarmButtonPushed, true);
            app.CooperativeFalseAlarmButton.Text = 'Cooperative False Alarm';
            app.CooperativeMissDetectionButton = uibutton(app.UIFigure, 'push');
            app.CooperativeMissDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CooperativeMissDetectionButtonPushed, true);
            app.CooperativeMissDetectionButton.Text = 'Cooperative Miss Detection';
            app.S4SuccessfulTXButton = uibutton(app.UIFigure, 'push');
            app.S4SuccessfulTXButton.ButtonPushedFcn = createCallbackFcn(app, @S4SuccessfulTXButtonPushed, true);
            app.S4SuccessfulTXButton.Text = 'S4 Successful TX';
            app.ChangingLoadsButton_2 = uibutton(app.UIFigure, 'push');
            app.ChangingLoadsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChangingLoadsButton_2Pushed, true);
            app.ChangingLoadsButton_2.Text = 'Changing Loads';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CRN_SIMULATOR_GUI_V0_2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end